classdef appout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_1     matlab.ui.control.RadioButton
        Button_3     matlab.ui.control.RadioButton
        Button_5     matlab.ui.control.RadioButton
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        UIAxes2      matlab.ui.control.UIAxes
        runplot      matlab.ui.control.Button
        Label_3      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        Label_5      matlab.ui.control.Label
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
        Label_6      matlab.ui.control.Label
        Switch       matlab.ui.control.ToggleSwitch
    end

    methods (Access = private)

        % Button pushed function: runplot
        function ButtonPusedrunplot(app, event)
            switchelc(app, event);
            
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2);
            %% 运行
            axis(app.UIAxes,'off')
            %app.UIAxes.Position= [200 200 200 200];
            %surf(app.UIAxes,peaks);
            
            
            tt = linspace(0,app.Slider.Value,100);
            app.UIAxes2.XLim = [0,app.Slider.Value];
            
            if app.EditField.Value<app.EditField_2.Value
                app.Label_5.Text = '增强';
            elseif app.EditField.Value>app.EditField_2.Value
                app.Label_5.Text = '减弱';
            else
                app.Label_5.Text = '不变';
            end
            
            %            appyy1=min([app.EditField.Value,app.EditField_2.Value]);
            appyy2=max([app.EditField.Value,app.EditField_2.Value]);
            app.UIAxes2.YLim = [0,appyy2+0.001];
            %%
            %% 调用按钮状态
            ButtonGroupSelectionChanged(app, event)
            if     app.Button_1.Value==true
                BBd = linspace(0,1,100);
                
            elseif app.Button_3.Value==true
                BBd = linspace(0,1,100);
                BBd = BBd.^2;
            elseif app.Button_5.Value==true
                BBd = linspace(0,1,100);
                BBd = 1-(BBd-1).^2;
            end
            bbb = 2500*gradient(BBd);
            %%
            BBdelt=app.EditField_2.Value-app.EditField.Value;
            BBtt=app.EditField.Value+BBdelt*BBd;
            %plot(app.UIAxes2,tt,BBtt,'LineWidth',2,'Color','red');
            
            %%
            x=linspace(-1,1,49);
            y=x;
            [xx,yy]=ndgrid(x,y);
            xx= 49*reshape(xx,[],1);
            yy= 49*reshape(yy,[],1);
            
            hhb1=scatter(app.UIAxes,xx,yy,'MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],...
                'Marker','x','SizeData',120,'LineWidth',0.5);
            hold(app.UIAxes,'on')
            %hhb2=scatter(app.UIAxes,1.5,1.5,'MarkerFaceColor',[1 0 0],'MarkerEdgeColor',[1 0 0],...
            %    'Marker','x','SizeData',50,'LineWidth',1);
            %set(hh3,'Marker','o','MarkerFaceColor','flat','MarkerEdgeColor','none','MarkerFaceColor',[1 0 0])
            %ax1=app.UIAxes;
            %%
            x66=linspace(-1,1,50);
            y66=x66;
            [xx66,yy66]=ndgrid(x66,y66);
            xx66= 49*reshape(xx66,[],1);
            yy66= 49*reshape(yy66,[],1);
            
            hhb2=scatter(app.UIAxes,xx66,yy66,'MarkerFaceColor',[0 0 0],...
                'MarkerEdgeColor',[0 0 0],...
                'Marker','x','SizeData',80,'LineWidth',0.5);
            if app.EditField.Value<app.EditField_2.Value
                set(hhb2,'Marker','o','SizeData',50,'MarkerFaceColor','flat','MarkerEdgeColor','none','MarkerFaceColor',[0 0 0])
            end
            
            %%
            [xx3,yy3]=ndgrid(x,y);
            rr= sqrt(xx3.^2+yy3.^2);
            
            eee = 50./rr;
            
            eex = yy3./sqrt(rr).*eee;
            eey = -xx3./sqrt(rr).*eee;
            
            % hhb3 = quiver(app.UIAxes3,10*xx3,10*yy3,eex,eey,4);
            
            % axis(app.UIAxes3,[-7.5,7.5,-7.5,7.5])
            %%
            oox = 0;ooy = 0;
            % r = 2;
            rrr=linspace(0,20,50);
            
            
            axes3qqq=[-7.5,7.5,-7.5,7.5];
            axis(app.UIAxes3,axes3qqq)
            if strcmpi(app.Switch.Value,'开')
                %disp('hua')
                
                for rr=rrr
                    rectangle(app.UIAxes3,'Position',[oox-rr,ooy-rr,2*rr,2*rr],'Curvature', [1 1],'LineWidth',1.5, ...
                        'EdgeColor',[0 0 0.8]);
                end
                
                %%
                %hold on
                
                uuu=0.05;
                if app.EditField.Value>app.EditField_2.Value
                    uuu=-uuu;
                end
                yyq1 = rrr(2:20)-uuu;
                xxq1 = 0*yyq1;
                
                yyq2 = yyq1+uuu;
                xxq2 = xxq1-5*uuu;
                
                yyq3 = yyq1+2*uuu;
                xxq3 = xxq1;
                
                f = [1,2,3];
                for ii=1:length(yyq1)
                    v=[xxq1(ii),yyq1(ii);xxq2(ii),yyq2(ii);xxq3(ii),yyq3(ii)];
                    patch(app.UIAxes3,'Faces',f,'Vertices',v,'FaceColor',[0 0 0.8]);
                    
                end
            end
            
            
            % h=rectangle('Position',[xx-r,yy-r,2*r,2*r],'Curvature', [1 1],'LineWidth',1.5, ...
            %         'EdgeColor',[1 0 0]);
            %     r2=r+20;
            %     set(h,'Position',[xx-r2,yy-r2,2*r2,2*r2],'EdgeColor',[0 0 0])
            
            %%
            nn=size(BBtt,2);
            for ii=1:nn
                xx2=xx/sqrt(BBtt(ii));
                yy2=yy/sqrt(BBtt(ii));
                set(hhb1,'XData',xx2,'YData',yy2);
                %%
                %xx662=xx66/sqrt(bbbtt(ii));
                %yy662=yy66/sqrt(bbbtt(ii));
                xx662=xx66/sqrt(bbb(ii));
                yy662=yy66/sqrt(bbb(ii));
                set(hhb2,'XData',xx662,'YData',yy662);
                
                
                %%
                axis(app.UIAxes,[-1.05,1.05,-1.05,1.05])
                cla(app.UIAxes2)
                plot(app.UIAxes2,tt(1:ii),BBtt(1:ii),'LineWidth',2,'Color','red');
                %%
                axes3qqq2=axes3qqq*bbb(ii)*0.02;
                
                axis(app.UIAxes3,axes3qqq2)
                
                drawnow
                pause((app.Slider.Value-5)/100+0.000003)
            end
            
            %pause(1)
            %set(hh,'Marker','o','MarkerFaceColor','flat','MarkerEdgeColor','none','MarkerFaceColor',[1 0 0])
            %for ii=10:-.2:1
            %    axis(ax1,[-ii,ii,-ii,ii])
            %
            %    drawnow
            %end
            %set(app.UIAxes,'BoxStyle','full')
            
            %get(app.UIAxes)
            cla(app.UIAxes3)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %            selectedButton = app.ButtonGroup.SelectedObject;
            %% 状态
            %%
            
        end

        % Value changed function: Switch
        function switchelc(app, event)
            value = app.Switch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '电磁感应';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '外加磁场变化模式';
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '均匀';
            app.Button_1.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '加速';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '减速';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '运行时间(秒)';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [5 20];
            app.Slider.MajorTickLabels = {'', '', '', '', '', '', ''};
            app.Slider.Value = 5;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '外加磁场随时间变化曲线')
            xlabel(app.UIAxes2, '时间')
            ylabel(app.UIAxes2, '磁场强度')
            app.runplot = uibutton(app.UIFigure, 'push');
            app.runplot.ButtonPushedFcn = createCallbackFcn(app, @ButtonPusedrunplot, true);
            app.runplot.Text = '运行';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '磁场初值';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 2500];
            app.EditField.Value = 11.2;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '磁场末值';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [1 2500];
            app.EditField_2.Value = 145.7;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '增减判定';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '感生电场';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @switchelc, true);
            app.Switch.Value = '关';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appout

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end